classdef aplikasi_bola_memantul < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        STOPButton                   matlab.ui.control.Button
        RUNButton                    matlab.ui.control.Button
        ParameterPanel               matlab.ui.container.Panel
        WaktuSimulasiEditField       matlab.ui.control.NumericEditField
        WaktuSimulasiEditFieldLabel  matlab.ui.control.Label
        KoefisienbEditField          matlab.ui.control.NumericEditField
        KoefisienbEditFieldLabel     matlab.ui.control.Label
        JarijarirEditField           matlab.ui.control.NumericEditField
        JarijarirEditFieldLabel      matlab.ui.control.Label
        GainKEditField               matlab.ui.control.NumericEditField
        GainKEditFieldLabel          matlab.ui.control.Label
        KontrolBolaMemantulLabel     matlab.ui.control.Label
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', "K", app.GainKEditField.Value)
            assignin('base', "r", app.JarijarirEditField.Value)
            assignin('base', "b", app.KoefisienbEditField.Value)
            assignin('base', "t_sim", app.WaktuSimulasiEditField.Value)

            run("BouncingBall.slx")
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            % Open VR Sink block on Simulink
            ret = find_system(bdroot(gcb),'Name','VR Sink');
            open_system(ret);
                        
            set_param('BouncingBall', 'SimulationCommand', 'start')
            set_param('BouncingBall', 'SimulationCommand', 'stop')

            sim("BouncingBall.slx");
%             x    =  missile_position(:,2);
%             y    = -missile_position(:,3);
%             plot(app.UIAxes_12,x,y,'LineWidth',1);
%             set(app.UIAxes_12,'FontSize',12);
%             xlabel(app.UIAxes_12,'\itNorth (m)');
%             ylabel(app.UIAxes_12,'\itEast - West (m)');
%             title(app.UIAxes_12,'Missile position from above','FontWeight','normal')
%               positionBall = evalin('base', 'positionBall');
              y     = positionBall(:,2);
              x     = positionBall(:,1);
              plot(app.UIAxes,x,y,'LineWidth',1);
              set(app.UIAxes,'FontSize',12);
              xlabel(app.UIAxes,'second (s)');
              ylabel(app.UIAxes, 'lintasan (m)');
              
              y     = velocityBall(:,2);
              x     = velocityBall(:,1);
              plot(app.UIAxes_2,x,y,'LineWidth',1);
              set(app.UIAxes_2,'FontSize',12);
              xlabel(app.UIAxes_2,'second (s)');
              ylabel(app.UIAxes_2, 'Velocity (m/s)');
              
              y     = accelerationBall(:,2);
              x     = accelerationBall(:,1);
              plot(app.UIAxes_3,x,y,'LineWidth',1);
              set(app.UIAxes_3,'FontSize',12);
              xlabel(app.UIAxes_3,'second (s)');
              ylabel(app.UIAxes_3, 'Acceleration (m/s^2)');
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            cla reset;
            set_param('BouncingBall', 'SimulationCommand', 'stop')
        end

        % Value changed function: GainKEditField
        function GainKEditFieldValueChanged(app, event)
            value = app.GainKEditField.Value;
            assignin('base', 'K', value);
        end

        % Value changed function: JarijarirEditField
        function JarijarirEditFieldValueChanged(app, event)
            value = app.JarijarirEditField.Value;
            assignin('base', 'r', value);
        end

        % Value changed function: KoefisienbEditField
        function KoefisienbEditFieldValueChanged(app, event)
            value = app.KoefisienbEditField.Value;
            assignin('base', 'b', value);
        end

        % Value changed function: WaktuSimulasiEditField
        function WaktuSimulasiEditFieldValueChanged(app, event)
            value = app.WaktuSimulasiEditField.Value;
            assignin('base', 't_sim', value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Ball Position')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Ball Velocity')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Ball Acceleration')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.KontrolBolaMemantulLabel = uilabel(app.UIFigure);
            app.KontrolBolaMemantulLabel.Text = 'Kontrol Bola Memantul';
            app.ParameterPanel = uipanel(app.UIFigure);
            app.ParameterPanel.Title = 'Parameter';
            app.GainKEditFieldLabel = uilabel(app.ParameterPanel);
            app.GainKEditFieldLabel.Text = 'Gain (K)';
            app.GainKEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.GainKEditField.ValueChangedFcn = createCallbackFcn(app, @GainKEditFieldValueChanged, true);
            app.JarijarirEditFieldLabel = uilabel(app.ParameterPanel);
            app.JarijarirEditFieldLabel.Text = 'Jari-jari (r)';
            app.JarijarirEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.JarijarirEditField.ValueChangedFcn = createCallbackFcn(app, @JarijarirEditFieldValueChanged, true);
            app.KoefisienbEditFieldLabel = uilabel(app.ParameterPanel);
            app.KoefisienbEditFieldLabel.Text = 'Koefisien (b)';
            app.KoefisienbEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.KoefisienbEditField.ValueChangedFcn = createCallbackFcn(app, @KoefisienbEditFieldValueChanged, true);
            app.WaktuSimulasiEditFieldLabel = uilabel(app.ParameterPanel);
            app.WaktuSimulasiEditFieldLabel.Text = 'Waktu Simulasi';
            app.WaktuSimulasiEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.WaktuSimulasiEditField.ValueChangedFcn = createCallbackFcn(app, @WaktuSimulasiEditFieldValueChanged, true);
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aplikasi_bola_memantul

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end